classdef MatlabApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        ReadButton                 matlab.ui.control.Button
        WeightValueReadingLabel    matlab.ui.control.Label
        gLabel_2                   matlab.ui.control.Label
        liftValue                  matlab.ui.control.Label
        LiftValueLabel             matlab.ui.control.Label
        gLabel                     matlab.ui.control.Label
        WeightValueLabel           matlab.ui.control.Label
        LiftValueReadingLabel      matlab.ui.control.Label
        weightValue                matlab.ui.control.Label
        RightPanel                 matlab.ui.container.Panel
        WLabel                     matlab.ui.control.Label
        powerValue                 matlab.ui.control.Label
        PowerValueLabel            matlab.ui.control.Label
        PowerConsumpReadingLabel   matlab.ui.control.Label
        ALabel                     matlab.ui.control.Label
        currentValue               matlab.ui.control.Label
        CurrentValueLabel          matlab.ui.control.Label
        CurrentSensorReadingLabel  matlab.ui.control.Label
        VLabel                     matlab.ui.control.Label
        voltageValue               matlab.ui.control.Label
        VoltageValueLabel          matlab.ui.control.Label
        VoltageSensorReadingLabel  matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ReadButton
        function ReadButtonPushed(app, event)
            s = serialport('COM14', 9600);
            configureTerminator(s, "CR/LF");
            flush(s);
               
            while true

               raw_data = readline(s);
                
               firstChar = extract(raw_data, 1);

               if firstChar == '1'
                    app.weightValue.Text = extractAfter(raw_data, ':');
               end

               if firstChar == '6'
                    app.voltageValue.Text = extractAfter(raw_data, ':');
               end

               if firstChar == '7'
                    app.currentValue.Text = extractAfter(raw_data, ':');
               end
      
            end
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {523, 523};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {436, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.weightValue = uilabel(app.LeftPanel);
            app.weightValue.Text = '00000';
            app.LiftValueReadingLabel = uilabel(app.LeftPanel);
            app.LiftValueReadingLabel.Text = 'Lift Value Reading';
            app.WeightValueLabel = uilabel(app.LeftPanel);
            app.WeightValueLabel.Text = 'Weight Value:';
            app.gLabel = uilabel(app.LeftPanel);
            app.gLabel.Text = 'g';
            app.LiftValueLabel = uilabel(app.LeftPanel);
            app.LiftValueLabel.Text = 'Lift Value:';
            app.liftValue = uilabel(app.LeftPanel);
            app.liftValue.Text = '0';
            app.gLabel_2 = uilabel(app.LeftPanel);
            app.gLabel_2.Text = 'g';
            app.WeightValueReadingLabel = uilabel(app.LeftPanel);
            app.WeightValueReadingLabel.Text = 'Weight Value Reading';
            app.ReadButton = uibutton(app.LeftPanel, 'push');
            app.ReadButton.ButtonPushedFcn = createCallbackFcn(app, @ReadButtonPushed, true);
            app.ReadButton.Text = 'Read';
            app.RightPanel = uipanel(app.GridLayout);
            app.VoltageSensorReadingLabel = uilabel(app.RightPanel);
            app.VoltageSensorReadingLabel.Text = 'Voltage Sensor Reading';
            app.VoltageValueLabel = uilabel(app.RightPanel);
            app.VoltageValueLabel.Text = 'Voltage Value:';
            app.voltageValue = uilabel(app.RightPanel);
            app.voltageValue.Text = '0';
            app.VLabel = uilabel(app.RightPanel);
            app.VLabel.Text = 'V';
            app.CurrentSensorReadingLabel = uilabel(app.RightPanel);
            app.CurrentSensorReadingLabel.Text = 'Current Sensor Reading';
            app.CurrentValueLabel = uilabel(app.RightPanel);
            app.CurrentValueLabel.Text = 'Current Value:';
            app.currentValue = uilabel(app.RightPanel);
            app.currentValue.Text = '0';
            app.ALabel = uilabel(app.RightPanel);
            app.ALabel.Text = 'A';
            app.PowerConsumpReadingLabel = uilabel(app.RightPanel);
            app.PowerConsumpReadingLabel.Text = 'Power Consump Reading';
            app.PowerValueLabel = uilabel(app.RightPanel);
            app.PowerValueLabel.Text = 'Power Value:';
            app.powerValue = uilabel(app.RightPanel);
            app.powerValue.Text = '0';
            app.WLabel = uilabel(app.RightPanel);
            app.WLabel.Text = 'W';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MatlabApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end