classdef loadcellDataDisplay1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ReadButton                matlab.ui.control.Button
        LoadcellDataDisplayLabel  matlab.ui.control.Label
        gLabel                    matlab.ui.control.Label
        WeightValueLabel          matlab.ui.control.Label
        weightValue               matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ReadButton
        function ReadButtonPushed(app, event)
             s = serialport('COM14', 9600);
            configureTerminator(s, "CR/LF");
            flush(s);
               
            while true

               raw_data = readline(s);
                
               firstChar = extract(raw_data, 1);

               if firstChar == '1'
                    app.weightValue.Text = extractAfter(raw_data, ':');
               end
      
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.weightValue = uilabel(app.UIFigure);
            app.weightValue.Text = '00000';
            app.WeightValueLabel = uilabel(app.UIFigure);
            app.WeightValueLabel.Text = 'Weight Value:';
            app.gLabel = uilabel(app.UIFigure);
            app.gLabel.Text = 'g';
            app.LoadcellDataDisplayLabel = uilabel(app.UIFigure);
            app.LoadcellDataDisplayLabel.Text = 'Loadcell Data Display';
            app.ReadButton = uibutton(app.UIFigure, 'push');
            app.ReadButton.ButtonPushedFcn = createCallbackFcn(app, @ReadButtonPushed, true);
            app.ReadButton.Text = 'Read';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = loadcellDataDisplay1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end