classdef loadcellDataLineGraph < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        PlotButton  matlab.ui.control.Button
        UIAxes      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            s = serialport('COM14', 9600);
            configureTerminator(s, "CR/LF");

            data = readline(s)
            flush(s);
            
            timeArr = [];
            loadcellDataArr = [];

            h = animatedline(app.UIAxes, 'Color', 'b', 'LineWidth', 2, 'MaximumNumPoints', Inf);

            while 1
                data = str2double(strsplit(readline(s), ','))

                time = data(1)
                loadcell = data(2)
                

                time = data(1) / 1000

                loadcell = data(2)

                timeArr = [timeArr, time];
                rollAngleArr = [loadcellDataArr, loadcell];


                clearpoints(h);
                addpoints(h, timeArr, loadcellDataArr);

                drawnow;
            end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Loadcell Data vs Time Graph')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Loadcell (g)')
            zlabel(app.UIAxes, 'Z')
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = loadcellDataLineGraph

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end