classdef wp2App4v3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Label_2                    matlab.ui.control.Label
        throttleValue2             matlab.ui.control.Label
        Throttle2Label             matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        throttleValue1             matlab.ui.control.Label
        Throttle1Label             matlab.ui.control.Label
        ThrottleValueReadingLabel  matlab.ui.control.Label
        StartButton                matlab.ui.control.Button
        gLabel                     matlab.ui.control.Label
        liftValue                  matlab.ui.control.Label
        LiftValueLabel             matlab.ui.control.Label
        LiftValueReadingLabel      matlab.ui.control.Label
        sLabel                     matlab.ui.control.Label
        degreesLabel_3             matlab.ui.control.Label
        degreesLabel_2             matlab.ui.control.Label
        degreesLabel               matlab.ui.control.Label
        IMUOrientaionReadingLabel  matlab.ui.control.Label
        timeValue                  matlab.ui.control.Label
        TimeLabel                  matlab.ui.control.Label
        YawLabel                   matlab.ui.control.Label
        yawValue                   matlab.ui.control.Label
        PitchLabel                 matlab.ui.control.Label
        pitchValue                 matlab.ui.control.Label
        RollLabel                  matlab.ui.control.Label
        rollValue                  matlab.ui.control.Label
        UIAxesOfThrottle2          matlab.ui.control.UIAxes
        UIAxesOfThrottle1          matlab.ui.control.UIAxes
        UIAxesOfLiftForce          matlab.ui.control.UIAxes
        UIAxesOfRollAngle          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            %% Serial Communication 
            s = serialport('COM14', 9600);
            configureTerminator(s, "CR/LF");

            %% Screen and Figure Setup 
            screen_property = get(0,'screensize');
            h = figure('outerposition', ...
                [0, screen_property(4)/2, ...
                screen_property(3)/2, screen_property(4)/2]);
            grid on; hold on;
            
            O  = [0,0,0]';
            e1 = [0,0,1]';
            e2 = [1,0,0]';
            e3 = [0,1,0]';

            p1 = plot3(  [O(1), e1(1)], ...
                [O(2), e1(2)], ...
                [O(3), e1(3)], ...
                'LineWidth',2,'Color','r');
            p2 = plot3(  [O(1), e2(1)], ...
                [O(2), e2(2)], ...
                [O(3), e2(3)], ...
                'LineWidth',2,'Color','g');
            p3 = plot3(  [O(1), e3(1)], ...
                [O(2), e3(2)], ...
                [O(3), e3(3)], ...
                'LineWidth',2,'Color','b');
            p4 = plot3(  [O(1), -e2(1)], ...
                [O(2), e2(2)], ...
                [O(3),-e2(3)], ...
                'LineWidth',2,'Color','b');
            
            % patch([0, 1, 0],[0, 0, 1],[0, 0, 0],'r', 'alpha')
            grid on;
            view(-30,30); axis equal
            xlabel('y','FontSize',16);
            ylabel('z','FontSize',16);
            zlabel('x','FontSize',16);
            xlim([-1,1]);
            ylim([-1,1]);
            zlim([-1,1]);

            %% Initialization
            data = readline(s)
            flush(s);
            
            h2 = animatedline(app.UIAxesOfRollAngle, 'Color', 'b', 'LineWidth', 2, 'MaximumNumPoints', Inf); 

            h3 = animatedline(app.UIAxesOfLiftForce, 'Color', 'b', 'LineWidth', 2, 'MaximumNumPoints', Inf);

            h4 = animatedline(app.UIAxesOfThrottle1, 'Color', 'b', 'LineWidth', 2, 'MaximumNumPoints', Inf);

            h5 = animatedline(app.UIAxesOfThrottle2, 'Color', 'b', 'LineWidth', 2, 'MaximumNumPoints', Inf);

            
            time_start = 0;
            
            %% Loop
            while 1
                
                data = str2double(strsplit(readline(s), ','))
                
                %% Collect Data
                %{
                time = data(1) 
                roll = data(2)
                pitch = data(3)
                yaw = data(4)
                loadcell = data(5)
                throttle1 = data(6)
                throttle2 = data(7)
                %}
                
                time = data(1) 

                %% Display Data 
                
                app.timeValue.Text = num2str(data(1)); % time
               
                app.rollValue.Text = num2str(data(2)); % roll

                app.pitchValue.Text = num2str(data(3)); % pitch 

                app.yawValue.Text = num2str(data(4)); % yaw

                app.liftValue.Text = num2str(data(5)); % lift 

                app.throttleValue1.Text = num2str(data(6)); % throttle 1
                
                app.throttleValue2.Text = num2str(data(7)); % throttle 2
                
                %% IMU Orientaion 3D Rendering
                if (time-time_start) >= 20

                    time_start = time;
         
                    th1 = 0;
                    th2 = data(2); % roll
                    th3 = data(3); % pitch
                   
                    R1 = [  cosd(th1), -sind(th1), 0;
                        sind(th1),  cosd(th1), 0;
                        0, 0, 1];
                    R2 = [  1, 0, 0;
                        0, cosd(th2), -sind(th2);
                        0, sind(th2),  cosd(th2)];
                    R3 = [  cosd(th3), 0, sind(th3);
                        0, 1, 0;
                        -sind(th3), 0, cosd(th3)];

                    e1_pr = R3*R2*R1*e1;
                    e2_pr = R3*R2*R1*e2;
                    e3_pr = R3*R2*R1*e3;
                    
                    set(p1, 'XData', [O(1), e1_pr(1)], ...
                        'YData', [O(2), e1_pr(2)], ...
                        'ZData', [O(3), e1_pr(3)])
                    set(p2, 'XData', [O(1), e2_pr(1)], ...
                        'YData', [O(2), e2_pr(2)], ...
                        'ZData', [O(3), e2_pr(3)])
                    set(p3, 'XData', [O(1), e3_pr(1)], ...
                        'YData', [O(2), e3_pr(2)], ...
                        'ZData', [O(3), e3_pr(3)])
                    set(p4, 'XData', [O(1), -e2_pr(1)], ...
                        'YData', [O(2), e2_pr(2)], ...
                        'ZData', [O(3), -e2_pr(3)])

                    timeInSecond = data(1) / 1000                    
    
                    % timeArr = [timeArr, timeInSecond];
                    % rollAngleArr = [rollAngleArr, roll];
                    % liftForceArr = [liftForceArr, loadcell];
                    % throttleOneArr = [throttleOneArr, throttle1];
                    % throttleTwoArr = [throttleTwoArr, throttle2];

                    % clearpoints(h2);
                    % clearpoints(h3);
                    % clearpoints(h4);
                    % clearpoints(h5);
                    
                    addpoints(h2, timeInSecond, data(2)); % roll
                    addpoints(h3, timeInSecond, data(5)); % lift 
                    addpoints(h4, timeInSecond, data(6)); % throttle 1
                    addpoints(h5, timeInSecond, data(7)); % throttle 2
                   

                    drawnow;
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesOfRollAngle = uiaxes(app.UIFigure);
            title(app.UIAxesOfRollAngle, 'Roll Angle vs Time Graph')
            xlabel(app.UIAxesOfRollAngle, 'Time (s)')
            ylabel(app.UIAxesOfRollAngle, 'Roll Angle (degrees)')
            zlabel(app.UIAxesOfRollAngle, 'Z')
            app.UIAxesOfLiftForce = uiaxes(app.UIFigure);
            title(app.UIAxesOfLiftForce, 'Lift Force vs Time Graph')
            xlabel(app.UIAxesOfLiftForce, 'Time (s)')
            ylabel(app.UIAxesOfLiftForce, 'Lift (g)')
            zlabel(app.UIAxesOfLiftForce, 'Z')
            app.UIAxesOfThrottle1 = uiaxes(app.UIFigure);
            title(app.UIAxesOfThrottle1, 'Throttle vs Time of Motor 1 Graph')
            xlabel(app.UIAxesOfThrottle1, 'Time (s)')
            ylabel(app.UIAxesOfThrottle1, 'Throttle 1 ')
            zlabel(app.UIAxesOfThrottle1, 'Z')
            app.UIAxesOfThrottle1.YLim = [1000 1800];
            app.UIAxesOfThrottle1.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxesOfThrottle1.YTick = [1000 1200 1400 1600 1800];
            app.UIAxesOfThrottle1.YTickLabel = {'1000'; '1200'; '1400'; '1600'; '1800'};
            app.UIAxesOfThrottle2 = uiaxes(app.UIFigure);
            title(app.UIAxesOfThrottle2, 'Throttle vs Time of Motor 2 Graph')
            xlabel(app.UIAxesOfThrottle2, 'Time (s)')
            ylabel(app.UIAxesOfThrottle2, 'Throttle 2')
            zlabel(app.UIAxesOfThrottle2, 'Z')
            app.UIAxesOfThrottle2.YLim = [1000 1800];
            app.UIAxesOfThrottle2.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxesOfThrottle2.YTick = [1000 1200 1400 1600 1800];
            app.rollValue = uilabel(app.UIFigure);
            app.rollValue.Text = '0';
            app.RollLabel = uilabel(app.UIFigure);
            app.RollLabel.Text = 'Roll: ';
            app.pitchValue = uilabel(app.UIFigure);
            app.pitchValue.Text = '0';
            app.PitchLabel = uilabel(app.UIFigure);
            app.PitchLabel.Text = 'Pitch';
            app.yawValue = uilabel(app.UIFigure);
            app.yawValue.Text = '0';
            app.YawLabel = uilabel(app.UIFigure);
            app.YawLabel.Text = 'Yaw';
            app.TimeLabel = uilabel(app.UIFigure);
            app.TimeLabel.Text = 'Time';
            app.timeValue = uilabel(app.UIFigure);
            app.timeValue.Text = '0';
            app.IMUOrientaionReadingLabel = uilabel(app.UIFigure);
            app.IMUOrientaionReadingLabel.Text = 'IMU Orientaion Reading';
            app.degreesLabel = uilabel(app.UIFigure);
            app.degreesLabel.Text = 'degrees';
            app.degreesLabel_2 = uilabel(app.UIFigure);
            app.degreesLabel_2.Text = 'degrees';
            app.degreesLabel_3 = uilabel(app.UIFigure);
            app.degreesLabel_3.Text = 'degrees';
            app.sLabel = uilabel(app.UIFigure);
            app.sLabel.Text = 's';
            app.LiftValueReadingLabel = uilabel(app.UIFigure);
            app.LiftValueReadingLabel.Text = 'Lift Value Reading';
            app.LiftValueLabel = uilabel(app.UIFigure);
            app.LiftValueLabel.Text = 'Lift Value:';
            app.liftValue = uilabel(app.UIFigure);
            app.liftValue.Text = '0';
            app.gLabel = uilabel(app.UIFigure);
            app.gLabel.Text = 'g';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ThrottleValueReadingLabel = uilabel(app.UIFigure);
            app.ThrottleValueReadingLabel.Text = 'Throttle Value Reading';
            app.Throttle1Label = uilabel(app.UIFigure);
            app.Throttle1Label.Text = 'Throttle 1:';
            app.throttleValue1 = uilabel(app.UIFigure);
            app.throttleValue1.Text = '0';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '%';
            app.Throttle2Label = uilabel(app.UIFigure);
            app.Throttle2Label.Text = 'Throttle 2:';
            app.throttleValue2 = uilabel(app.UIFigure);
            app.throttleValue2.Text = '0';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '%';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = wp2App4v3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end